classdef enclosos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        enclososUIFigure                matlab.ui.Figure
        datapointsPanel                 matlab.ui.container.Panel
        clearButton                     matlab.ui.control.Button
        setButton                       matlab.ui.control.Button
        UsedatapointsasnegativesamplesLabel  matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        workspacePanel                  matlab.ui.container.Panel
        workspaceTextArea               matlab.ui.control.TextArea
        workspaceTextAreaLabel          matlab.ui.control.Label
        samplingTabGroup                matlab.ui.container.TabGroup
        randomTab                       matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        numsamplesEditField             matlab.ui.control.NumericEditField
        numsamplesEditFieldLabel        matlab.ui.control.Label
        gridTab                         matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        xyresolutionEditField           matlab.ui.control.EditField
        xyresolutionEditFieldLabel      matlab.ui.control.Label
        methodTabGroup                  matlab.ui.container.TabGroup
        SVMTab                          matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        kernelscaleSpinner              matlab.ui.control.Spinner
        kernelscaleSpinnerLabel         matlab.ui.control.Label
        boxconstraintSpinner            matlab.ui.control.Spinner
        boxconstraintSpinnerLabel       matlab.ui.control.Label
        polynomialdegreeSpinner         matlab.ui.control.Spinner
        polynomialdegreeSpinnerLabel    matlab.ui.control.Label
        PSSTab                          matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        polynomialdegreeSpinner_2       matlab.ui.control.Spinner
        polynomialdegreeSpinner_2Label  matlab.ui.control.Label
        EMTab                           matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        deltaSpinner                    matlab.ui.control.Spinner
        deltaSpinnerLabel               matlab.ui.control.Label
        polynomialdegreeSpinner_3       matlab.ui.control.Spinner
        polynomialdegreeSpinner_3Label  matlab.ui.control.Label
        savetofilePanel                 matlab.ui.container.Panel
        outputfilenameEditField         matlab.ui.control.EditField
        saveButton                      matlab.ui.control.Button
        drawpolygonPanel                matlab.ui.container.Panel
        polypointsTextArea              matlab.ui.control.TextArea
        drawaddpolygonButton            matlab.ui.control.Button
        logTextArea                     matlab.ui.control.TextArea
        computeButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        polygons = {}
        p
        samples_scatter
        levelcurve_line
        ref_data
        ref_data_scatter
    end
    
    methods (Access = private)
        function append_to_textarea(app, text)
            time_now = datetime('now', 'timezone', 'local');
            time = datetime(time_now, 'timezone', 'local', 'format', 'uuuu-MM-dd''T''HH:mm:ss.SSS');
            timestamp_str = char(time);

            if ~isempty(app.logTextArea.Value{1})
                app.logTextArea.Value = [app.logTextArea.Value(:)', {['[', timestamp_str, '] ', text]}];
            else
                app.logTextArea.Value = {['[', timestamp_str, '] ', text]};
            end
            scroll(app.logTextArea, 'bottom');
            drawnow;
        end
        
        function results = deleted_poly(app, src, evt)
            tmp_cellarray = {};
            for i = 1:length(app.polygons)
                if app.polygons{i} ~= src
                    tmp_cellarray{end+1} = app.polygons{i};
                end
            end
            app.polygons = tmp_cellarray;
        end
        
        function results = limitsChangedCallbackFcn(app, rulerHand, limChgData, xy)
            value = app.workspaceTextArea.Value{:};
            tmp_value = eval(value);
            switch xy
                case 'x'
                    tmp_value(1:2) = limChgData.NewLimits;
                case 'y'
                    tmp_value(3:4) = limChgData.NewLimits;
            end
            app.workspaceTextArea.Value = {mat2str(tmp_value)};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.UIAxes, 'on');
            app.UIAxes.XAxis.LimitsChangedFcn = @(hObj, data) app.limitsChangedCallbackFcn(hObj, data, 'x');
            app.UIAxes.YAxis.LimitsChangedFcn = @(hObj, data) app.limitsChangedCallbackFcn(hObj, data, 'y');
        end

        % Value changed function: workspaceTextArea
        function workspaceTextAreaValueChanged(app, event)
            value = app.workspaceTextArea.Value{:};
            tmp_value = eval(value);
            xlim(app.UIAxes, tmp_value(1:2));
            ylim(app.UIAxes, tmp_value(3:4));
        end

        % Button pushed function: drawaddpolygonButton
        function drawaddpolygonButtonPushed(app, event)
            if isempty(app.polypointsTextArea.Value{:})
                app.polygons{end+1} = drawpolygon(app.UIAxes, 'color', 'black', 'FaceAlpha', 0.25);
            else
                xlimits = app.UIAxes.XLim;
                ylimits = app.UIAxes.YLim;
    
                value = app.polypointsTextArea.Value{:};
                tmp_value = eval(value);
                app.polygons{end+1} = drawpolygon(app.UIAxes, 'color', 'black', 'FaceAlpha', 0.25, 'Position', tmp_value);
                app.polypointsTextArea.Value = {''};

                xlim(app.UIAxes, xlimits);
                ylim(app.UIAxes, ylimits);
            end
            app.polygons{end}.Color = 'black';

            addlistener(app.polygons{end}, 'DeletingROI', @app.deleted_poly);
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            if isempty(app.polygons)
                app.append_to_textarea('[Error] Add at least one polygon');
                return;
            end

            dim = 2;

            samples = [];
            labels = [];
            weights = [];

            app.append_to_textarea('Generate weighted sample points');
            rng(1, 'twister');
            weight_in_poly = 8;
            weight_not_in_poly = 1;
            include_poly_points = true;

            xlimits = app.UIAxes.XLim;
            ylimits = app.UIAxes.YLim;

            sampling_method = app.samplingTabGroup.SelectedTab.Title;
            switch sampling_method
                case 'random'
                    num_samples = app.numsamplesEditField.Value;
                    samples = rand(num_samples, dim) .* [repmat(xlimits(2) - xlimits(1), num_samples, 1), repmat(ylimits(2) - ylimits(1), num_samples, 1)] + [repmat(xlimits(1), num_samples, 1), repmat(ylimits(1), num_samples, 1)];
                case 'grid'
                    value = app.xyresolutionEditField.Value;
                    tmp_value = eval(value);
                    x = linspace(xlimits(1), xlimits(2), tmp_value(1));
                    y = linspace(ylimits(1), ylimits(2), tmp_value(2));
                    num_samples = tmp_value(1)*tmp_value(2);
                    [X,Y] = meshgrid(x,y);
                    samples = [X(:), Y(:)];
            end
            
            labels = -1 * ones(num_samples, 1);
            weights = weight_in_poly * ones(num_samples, 1);
            
            cur_poly_points = app.polygons{1}.Position;
            tmp = inpolygon(samples(:, 1), samples(:, 2), cur_poly_points(:, 1), cur_poly_points(:, 2));
            for i = 2:length(app.polygons)
                cur_poly_points = app.polygons{i}.Position;
                tmp = tmp + inpolygon(samples(:, 1), samples(:, 2), cur_poly_points(:, 1), cur_poly_points(:, 2));
            end
            are_in_poly = logical(tmp);
            labels(are_in_poly) = 1;
            % weights(are_in_poly) = weight_in_poly;
            weights(~are_in_poly) = weight_not_in_poly;
            
            % are_in_poly_tmp = logical(inpolygon(samples(:, 1), samples(:, 2), polypoints1(:, 1), polypoints1(:, 2)));
            % weights(are_in_poly_tmp) = 8;

            % add negative datapoints
            if ~isempty(app.ref_data) && app.CheckBox.Value
                samples = [samples; app.ref_data];
                labels = [labels; -1 * ones(size(app.ref_data, 1), 1)];
                weights = [weights; 2 * ones(size(app.ref_data, 1), 1)]; % hardcoded weight to match legacy data
            end
            
            % add obstacle vertices
            if include_poly_points
                for i = 1:length(app.polygons)
                    cur_poly_points = app.polygons{i}.Position;
                    samples = [samples; cur_poly_points];
                    labels = [labels; ones(size(cur_poly_points, 1), 1)];
                    weights = [weights; weight_in_poly * ones(size(cur_poly_points, 1), 1)];
                end
            end
            
            if ~isempty(app.samples_scatter)
                delete(app.samples_scatter);
            end
            app.samples_scatter = scatter(app.UIAxes, samples(:, 1), samples(:, 2), labels+2, 'blue');

            rng(1, 'twister');
            app.append_to_textarea('Compute p ...');
            approach = app.methodTabGroup.SelectedTab.Title;
            switch approach
                case 'SVM'
                    if ~exist('svm.m')
                        addpath('svm');
                    end
                    app.p = svm(samples, labels, weights, deg=app.polynomialdegreeSpinner.Value, boxconstraint=app.boxconstraintSpinner.Value, kernelscale=app.kernelscaleSpinner.Value);
                case 'PSS'
                    if ~exist('pss.m')
                        addpath('pss');
                    end
                    app.p = pss(samples(labels == 1, :), deg=app.polynomialdegreeSpinner_2.Value);
                case 'EM'
                    if ~exist('em.m')
                        addpath('em');
                    end
                    app.p = em(samples(labels == 1, :), deg=app.polynomialdegreeSpinner_3.Value, delta=app.deltaSpinner.Value);
            end
            app.append_to_textarea('... done');
            p = app.p;
            app.append_to_textarea(['p(x1, x2) = ' char(p)]);

            if ~isempty(app.levelcurve_line)
                delete(app.levelcurve_line);
            end
            app.levelcurve_line = fimplicit(app.UIAxes, formula(app.p), [xlimits, ylimits], 'color', 'red', 'linewidth', 2);
            app.append_to_textarea('Plot p==0');
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            filename = [app.outputfilenameEditField.Value '.mat'];
            p = app.p;
            save(filename, 'p', '-mat');
            file = which(filename);
            app.append_to_textarea(['Save p to ' file]);
        end

        % Button pushed function: setButton
        function setButtonPushed(app, event)
            [file,location] = uigetfile('*.txt', 'Select file to open');
            figure(app.enclososUIFigure);
            fullpath = strcat(location, file);

            app.ref_data = readmatrix(fullpath);

            xlimits = app.UIAxes.XLim;
            ylimits = app.UIAxes.YLim;

            if ~isempty(app.ref_data_scatter)
                delete(app.ref_data_scatter);
            end
            app.ref_data_scatter = scatter(app.UIAxes, app.ref_data(:, 1), app.ref_data(:, 2), 1, 'black');

            xlim(app.UIAxes, xlimits);
            ylim(app.UIAxes, ylimits);
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            app.ref_data = [];
            if ~isempty(app.ref_data_scatter)
                delete(app.ref_data_scatter);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.enclososUIFigure = uifigure('Visible', 'off');
            app.enclososUIFigure.Name = 'enclosos';
            app.enclososUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.UIAxes = uiaxes(app.enclososUIFigure);
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            app.computeButton = uibutton(app.enclososUIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            app.logTextArea = uitextarea(app.enclososUIFigure);
            app.logTextArea.Editable = 'off';
            app.logTextArea.Placeholder = 'log';
            app.drawpolygonPanel = uipanel(app.enclososUIFigure);
            app.drawaddpolygonButton = uibutton(app.drawpolygonPanel, 'push');
            app.drawaddpolygonButton.ButtonPushedFcn = createCallbackFcn(app, @drawaddpolygonButtonPushed, true);
            app.drawaddpolygonButton.Tooltip = {'If text field is empty, click button and then draw a polygon on the figure.'; 'If the text field is not empty, a polygon with the specified vertices will be added.'};
            app.drawaddpolygonButton.Text = 'draw/add polygon';
            app.polypointsTextArea = uitextarea(app.drawpolygonPanel);
            app.polypointsTextArea.Placeholder = '[x1,y1;x2,y2;...]';
            app.savetofilePanel = uipanel(app.enclososUIFigure);
            app.saveButton = uibutton(app.savetofilePanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.outputfilenameEditField = uieditfield(app.savetofilePanel, 'text');
            app.outputfilenameEditField.Tooltip = {'output filename'};
            app.outputfilenameEditField.Value = 'tmp_poly';
            app.methodTabGroup = uitabgroup(app.enclososUIFigure);
            app.methodTabGroup.Tooltip = {'method'};
            app.SVMTab = uitab(app.methodTabGroup);
            app.SVMTab.Title = 'SVM';
            app.GridLayout = uigridlayout(app.SVMTab);
            app.polynomialdegreeSpinnerLabel = uilabel(app.GridLayout);
            app.polynomialdegreeSpinnerLabel.Text = 'polynomial degree';
            app.polynomialdegreeSpinner = uispinner(app.GridLayout);
            app.polynomialdegreeSpinner.Limits = [1 Inf];
            app.polynomialdegreeSpinner.RoundFractionalValues = 'on';
            app.polynomialdegreeSpinner.Value = 4;
            app.boxconstraintSpinnerLabel = uilabel(app.GridLayout);
            app.boxconstraintSpinnerLabel.Text = 'boxconstraint';
            app.boxconstraintSpinner = uispinner(app.GridLayout);
            app.boxconstraintSpinner.Step = 100;
            app.boxconstraintSpinner.Value = 1600;
            app.kernelscaleSpinnerLabel = uilabel(app.GridLayout);
            app.kernelscaleSpinnerLabel.Text = 'kernelscale';
            app.kernelscaleSpinner = uispinner(app.GridLayout);
            app.kernelscaleSpinner.Step = 0.1;
            app.kernelscaleSpinner.Value = 0.48;
            app.PSSTab = uitab(app.methodTabGroup);
            app.PSSTab.Title = 'PSS';
            app.GridLayout2 = uigridlayout(app.PSSTab);
            app.polynomialdegreeSpinner_2Label = uilabel(app.GridLayout2);
            app.polynomialdegreeSpinner_2Label.Text = 'polynomial degree';
            app.polynomialdegreeSpinner_2 = uispinner(app.GridLayout2);
            app.polynomialdegreeSpinner_2.Limits = [1 Inf];
            app.polynomialdegreeSpinner_2.RoundFractionalValues = 'on';
            app.polynomialdegreeSpinner_2.Value = 4;
            app.EMTab = uitab(app.methodTabGroup);
            app.EMTab.Title = 'EM';
            app.GridLayout3 = uigridlayout(app.EMTab);
            app.polynomialdegreeSpinner_3Label = uilabel(app.GridLayout3);
            app.polynomialdegreeSpinner_3Label.Text = 'polynomial degree';
            app.polynomialdegreeSpinner_3 = uispinner(app.GridLayout3);
            app.polynomialdegreeSpinner_3.Limits = [1 Inf];
            app.polynomialdegreeSpinner_3.RoundFractionalValues = 'on';
            app.polynomialdegreeSpinner_3.Value = 2;
            app.deltaSpinnerLabel = uilabel(app.GridLayout3);
            app.deltaSpinnerLabel.Text = 'delta';
            app.deltaSpinner = uispinner(app.GridLayout3);
            app.samplingTabGroup = uitabgroup(app.enclososUIFigure);
            app.samplingTabGroup.Tooltip = {'sampling'};
            app.randomTab = uitab(app.samplingTabGroup);
            app.randomTab.Title = 'random';
            app.GridLayout4 = uigridlayout(app.randomTab);
            app.numsamplesEditFieldLabel = uilabel(app.GridLayout4);
            app.numsamplesEditFieldLabel.Text = 'num samples';
            app.numsamplesEditField = uieditfield(app.GridLayout4, 'numeric');
            app.numsamplesEditField.Value = 2000;
            app.gridTab = uitab(app.samplingTabGroup);
            app.gridTab.Title = 'grid';
            app.GridLayout5 = uigridlayout(app.gridTab);
            app.xyresolutionEditFieldLabel = uilabel(app.GridLayout5);
            app.xyresolutionEditFieldLabel.Text = 'x-y resolution';
            app.xyresolutionEditField = uieditfield(app.GridLayout5, 'text');
            app.xyresolutionEditField.Value = '[50,50]';
            app.workspacePanel = uipanel(app.enclososUIFigure);
            app.workspaceTextAreaLabel = uilabel(app.workspacePanel);
            app.workspaceTextAreaLabel.Text = 'workspace';
            app.workspaceTextArea = uitextarea(app.workspacePanel);
            app.workspaceTextArea.ValueChangedFcn = createCallbackFcn(app, @workspaceTextAreaValueChanged, true);
            app.workspaceTextArea.Tooltip = {'[xmin xmax ymin ymax]'};
            app.workspaceTextArea.Value = {'[0 1 0 1]'};
            app.datapointsPanel = uipanel(app.enclososUIFigure);
            app.CheckBox = uicheckbox(app.datapointsPanel);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.UsedatapointsasnegativesamplesLabel = uilabel(app.datapointsPanel);
            app.UsedatapointsasnegativesamplesLabel.Text = 'Use datapoints as negative samples';
            app.setButton = uibutton(app.datapointsPanel, 'push');
            app.setButton.ButtonPushedFcn = createCallbackFcn(app, @setButtonPushed, true);
            app.setButton.Text = 'set';
            app.clearButton = uibutton(app.datapointsPanel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            % Show the figure after all components are created
            app.enclososUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = enclosos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.enclososUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.enclososUIFigure)
        end
    end
end